classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        nolisteningLabel              matlab.ui.control.Label
        SendButton                    matlab.ui.control.Button
        RecivedmessageEditField       matlab.ui.control.EditField
        RecivedmessageEditFieldLabel  matlab.ui.control.Label
        ListenButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        State % stan polaczenia
        UdpPort % port
        Data % data thats readed
        looptimer
        Message
    end
    
    methods (Access = public)
        
        
        function petla(app,varargin)
            if app.State == 1
                app.Data = read(app.UdpPort,1);
                app.Message(end+1) = app.Data;
                if app.Data(end) == 59
                    app.RecivedmessageEditField.Value = char(app.Message(1:end-1));
                    app.Message = [];
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.State = 2;
            app.Message = [];
            app.looptimer = timer("ExecutionMode","fixedRate","Period",0.001,"BusyMode","queue","TimerFcn",@(varargin)app.petla);            
        end

        % Button pushed function: ListenButton
        function ListenButtonPushed(app, event)
            if app.State == 1
                app.State = 2;
            elseif app.State == 2
                app.State = 1;
            end
            if app.State == 1
                app.UdpPort = udpport('LocalPort', 25565);
                app.nolisteningLabel.Text = 'listening';
                app.ListenButton.Text = 'STOP';
                start(app.looptimer);
            end
            if app.State == 2
                delete(app.UdpPort);
                app.nolisteningLabel.Text = '';
                app.ListenButton.Text = 'Listen';
                stop(app.looptimer);
            end
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.RecivedmessageEditField.Value = char(app.Data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListenButton = uibutton(app.UIFigure, 'push');
            app.ListenButton.ButtonPushedFcn = createCallbackFcn(app, @ListenButtonPushed, true);
            app.ListenButton.Text = 'Listen';
            app.RecivedmessageEditFieldLabel = uilabel(app.UIFigure);
            app.RecivedmessageEditFieldLabel.Text = 'Recived message';
            app.RecivedmessageEditField = uieditfield(app.UIFigure, 'text');
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.nolisteningLabel = uilabel(app.UIFigure);
            app.nolisteningLabel.Text = 'no listening';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end